classdef calibApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProCamCalibUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CaptureTab                     matlab.ui.container.Tab
        ProjectorControlPanel          matlab.ui.container.Panel
        ProjecionScreenDropDown        matlab.ui.control.DropDown
        ProjecionScreenDropDownLabel   matlab.ui.control.Label
        ProjectorPreviewButton         matlab.ui.control.StateButton
        ProjectorBrightnessSlider      matlab.ui.control.Slider
        ProjectorBrightnessLabel       matlab.ui.control.Label
        DatafolderLabel_CapTab         matlab.ui.control.Label
        BrowseCaptureSaveFolderButton  matlab.ui.control.Button
        PreviewPanelCapture            matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        CameraColorGridImageLabel      matlab.ui.control.Label
        CameraLightImageLabel          matlab.ui.control.Label
        ProjectorinputImageLabel       matlab.ui.control.Label
        CamColorGridImg                matlab.ui.control.Image
        CamLightGridImg                matlab.ui.control.Image
        ProGridImg                     matlab.ui.control.Image
        CameraControlPanel             matlab.ui.container.Panel
        CameraAdjusmentPanel           matlab.ui.container.Panel
        FocusModeDropDownLabel         matlab.ui.control.Label
        FocusModeDropDown              matlab.ui.control.DropDown
        ExposureTimesSliderLabel       matlab.ui.control.Label
        ExposureTimesSlider            matlab.ui.control.Slider
        BrightnessSliderLabel          matlab.ui.control.Label
        BrightnessSlider               matlab.ui.control.Slider
        BacklightCompensationDropDownLabel  matlab.ui.control.Label
        BacklightCompensationDropDown  matlab.ui.control.DropDown
        WhiteBalanceModeDropDownLabel  matlab.ui.control.Label
        WhiteBalanceModeDropDown       matlab.ui.control.DropDown
        ExposureModeDropDownLabel      matlab.ui.control.Label
        ExposureModeDropDown           matlab.ui.control.DropDown
        WhiteBalanceSliderLabel        matlab.ui.control.Label
        WhiteBalanceSlider             matlab.ui.control.Slider
        SharpnessSliderLabel           matlab.ui.control.Label
        SharpnessSlider                matlab.ui.control.Slider
        SaturationSliderLabel          matlab.ui.control.Label
        SaturationSlider               matlab.ui.control.Slider
        HueSliderLabel                 matlab.ui.control.Label
        HueSlider                      matlab.ui.control.Slider
        GammaSliderLabel               matlab.ui.control.Label
        GammaSlider                    matlab.ui.control.Slider
        GainSliderLabel                matlab.ui.control.Label
        GainSlider                     matlab.ui.control.Slider
        ExposureSliderLabel            matlab.ui.control.Label
        ExposureSlider                 matlab.ui.control.Slider
        ContrastSliderLabel            matlab.ui.control.Label
        ContrastSlider                 matlab.ui.control.Slider
        ResetButton                    matlab.ui.control.Button
        FixtomanualLabel               matlab.ui.control.Label
        FixtooffLabel                  matlab.ui.control.Label
        ResolutionDropDown             matlab.ui.control.DropDown
        ResolutionDropDownLabel        matlab.ui.control.Label
        CameraNameDropDown             matlab.ui.control.DropDown
        CameraNameDropDownLabel        matlab.ui.control.Label
        CamPreviewButton               matlab.ui.control.StateButton
        CaptureButton                  matlab.ui.control.Button
        CalibrationTab                 matlab.ui.container.Tab
        CalibrationResultsTextArea     matlab.ui.control.TextArea
        CalibrationResultsTextAreaLabel  matlab.ui.control.Label
        PreviewPanelCalibration        matlab.ui.container.Panel
        PreviewImageTypeButtonGroup    matlab.ui.container.ButtonGroup
        colorGridButton                matlab.ui.control.RadioButton
        lightGridButton                matlab.ui.control.RadioButton
        CalibImage                     matlab.ui.control.Image
        ManualSegButton                matlab.ui.control.Button
        AutoSegButton                  matlab.ui.control.Button
        LoadCalibrationButton          matlab.ui.control.Button
        DatafolderLabel_CalibTab       matlab.ui.control.Label
        ImagesPanel                    matlab.ui.container.Panel
        DeleteButton                   matlab.ui.control.Button
        ImageListBox                   matlab.ui.control.ListBox
        BrowseCalibrationFolderButton  matlab.ui.control.Button
        SelectAllButton                matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        EpipolarThreshpixelEditFieldLabel  matlab.ui.control.Label
        EpipolarThreshpixelEditField   matlab.ui.control.NumericEditField
        CheckerboardSquareSizemmEditFieldLabel  matlab.ui.control.Label
        CheckerboardSquareSizemmEditField  matlab.ui.control.NumericEditField
        CalibrateButton                matlab.ui.control.Button
        VerbosedebugCheckBox           matlab.ui.control.CheckBox
        UseExistingCamCornersCheckBox  matlab.ui.control.CheckBox
        ReconstructButton              matlab.ui.control.Button
        UseParallelCheckBox            matlab.ui.control.CheckBox
        UseExistingMaskCheckBox        matlab.ui.control.CheckBox
        UseDistortionCheckBox          matlab.ui.control.CheckBox
        VerbosedebugCheckBox_2         matlab.ui.control.CheckBox
        UseEdgesCheckBox               matlab.ui.control.CheckBox
        AboutTab                       matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        GitHubHyperlink                matlab.ui.control.Hyperlink
        VersionLabel                   matlab.ui.control.Label
        ConfLabel                      matlab.ui.control.Label
        AuthorsLabel                   matlab.ui.control.Label
        CopyrightLabel                 matlab.ui.control.Label
        TitleLabel                     matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        DeleteMenu                     matlab.ui.container.Menu
    end

    %% License
    % ACADEMIC OR NON-PROFIT ORGANIZATION NONCOMMERCIAL RESEARCH USE ONLY
    % Copyright (c) 2018 Bingyao Huang
    % All rights reserved.

    % Redistribution and use in source and binary forms, with or without
    % modification, are permitted provided that the following conditions are met:

    % The above copyright notice and this permission notice shall be included in all
    % copies or substantial portions of the Software.
    %
    % If you publish results obtained using this software, please cite our paper.

    % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
    % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
    % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
    % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
    % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
    % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
    % SOFTWARE.
    properties (Access = private)
        % camera properties
        cam % webcam object used to capture image
        camList % a list of connected cameras that are recognized by matlab
        camInfo % all cameras' properties, such as birghtnees, exposure etc.
        camSliderProperties
        camDropDownProperties
        camPreviewImg % screenshot of the camera image
        camPreviewFig % camera preivew figure
        CamExposureTime % camera exposure time

        % projector properties
        imPattern
        screenSize
        prjFigHandel
        prjBrightness = 1.0
%         imgCounter = 1 % count how many images captured so far

        previewCalibImageType = 'lightGrid' % Description
    end

    properties (Access = public)
        % calibration data root and folder name
        dataRoot = fullfile(pwd, 'data')
        dataName
        calibInfo % calibration info to be saved to file
        calibOption % calibration options to pass to calibrateGUI
        reconOption % reconstruction options to pass to calibrateGUI
        stereoParams % calibrated/loaded stereo parameters
    end

    methods (Access = public)

        function initCameras(app)
            %             camList = imaqhwinfo('winvideo'); % windows
            %             camList = imaqhwinfo('macvideo'); % linux
            %             camList = imaqhwinfo('linuxvideo'); % mac

            %             for i = numel(camList)
            %                 curDevice = imaqhwinfo(adaptorList.InstalledAdaptors{i});
            %
            %             end

            app.camList = webcamlist();

            % error if camera not found
            if(numel(app.camList) == 0)
                uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
            else
                app.CameraNameDropDown.Items = app.camList;

                % controllable camea properties
                app.camSliderProperties = {'Brightness', 'Contrast', 'Exposure', 'Gain', 'Gamma', 'Hue', 'Saturation', 'Sharpness', 'WhiteBalance'};
                app.camDropDownProperties = {'BacklightCompensation', 'ExposureMode', 'WhiteBalanceMode', 'FocusMode'};
                %                 app.CameraAdjusmentPanel.Visible = false;
                app.CamExposureTime = app.ExposureTimesSlider.Value;
                % get camera infos, such as supported register values
                %                 getCamInfo(app);
                % display camera names in dropdown list
                %                 app.CameraNameDropDown.Items = {camList.DeviceInfo.DeviceName};
                %                 app.CameraNameDropDown.ItemsData = 1:numel(app.CameraNameDropDown.Items);
                %                 app.CameraNameDropDown.Items = {camList.DeviceInfo.DeviceName};

                %                 app.CameraNameDropDown.Value = camList{1};

                %                 app.ResolutionDropDown.Items =

                % start cam
                %                 app.cam = webcam(app.camList{1});
                %                 updateCamResolutionDropDown(app);
                %                 updateCamControlPannel(app, 1);
                %
                %                 % open cam preview window
                %                 app.camPreviewImg = preview(app.cam);
            end

        end

        %% update the camera resolution dropdown list
        function updateCamResolutionDropDown(app)
            app.ResolutionDropDown.Items = app.cam.AvailableResolutions;
            app.ResolutionDropDown.Value = app.cam.Resolution;
        end

        %         function getCamInfo(app, camID)

        %             f = waitbar(0, 'Detecting connected webcams...');
        %             for camID = 1:numel(app.camList)
        %                 waitbar(camID/numel(app.camList), f, [num2str(camID), ' webcam discovered...']);
        %                 src = getselectedsource(videoinput('winvideo', camID)); % slow
        %                 app.camInfo{camID} = src.propinfo;

        %                 getCamPropertyConstraint(app, camID);
        %             end

        %             close(f);
        %         end

        function updateCamControlPannel(app, camID)
            initCamSliders(app, camID);
            initCamDropDowns(app, camID);
            resetCamDropDowns(app, camID);
        end


        function getScreenInfo(app)
            app.screenSize = round(get(0, 'MonitorPositions'));
            numMonitors = size(app.screenSize, 1);

            for i=1:numMonitors
                app.ProjecionScreenDropDown.Items{i} = ['[Screen: ', num2str(i), '] ', num2str(app.screenSize(i,3)), 'x', num2str(app.screenSize(i,4))];
            end

            app.ProjecionScreenDropDown.ItemsData = 1:numMonitors;

            % init projector image
            screenID = app.ProjecionScreenDropDown.Value;
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
%             previewImage(app, 'ProUIAxes', app.imPattern);
            app.ProGridImg.ImageSource = app.imPattern;
        end

        function figHandle = initPrjWindow(app, screenID, im)
            %             app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            figHandle = figure('MenuBar', 'none', 'ToolBar', 'none');
            imshow(im);

            axeHandle = gca;
            set(axeHandle, 'Position', [0 0 1 1])
            set(figHandle, 'Position', app.screenSize(screenID,:))
            set(figHandle, 'WindowState', 'fullscreen')

            % speedup projection
            set(gca, 'xlimmode','manual',...
                'ylimmode','manual',...
                'zlimmode','manual',...
                'climmode','manual',...
                'alimmode','manual');
            set(figHandle,'doublebuffer','off');
        end

        function updateCalibrationResultText(app, calibFileName, stereoParams)
            app.CalibrationResultsTextArea.Value = {
                sprintf('File name:     %s\n\t', calibFileName);
                '----------------------- Intrinsics ---------------------'
                sprintf('Camera projection matrix (camK):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.camK', 1, 9));
                sprintf('Camera distortion coefficients (camKc):\n[%12.3f, %12.3f, %12.3f %12.3f]\n\t', stereoParams.camKc(:));
                sprintf('Projector projection matrix (prjK):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.prjK', 1, 9));
                sprintf('Projector distortion coefficients (prjKc):\n[%12.3f, %12.3f, %12.3f %12.3f]\n\t', stereoParams.prjKc(:));
                '----------------------- Extrinsics ---------------------'
                sprintf('Rotation matrix (R):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.R', 1, 9));
                sprintf('Translation vector (T):\n[%12.3f, %12.3f, %12.3f]\n\t', stereoParams.T(:));
                sprintf('Essential matrix (E):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.E', 1, 9));
                sprintf('Fundamental matrix (F):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.F', 1, 9));
                '------------------------ Errors ------------------------'
                sprintf('Camera reprojection error:\n%5.3f\n\t', stereoParams.camReprojErr);
                sprintf('Projector reprojection error:\n%5.3f\n\t', stereoParams.prjReprojErr);
                sprintf('Stereo reprojection error:\n%5.3f\n\t', stereoParams.stereoReprojErr);
                sprintf('Camera per-view reprojection error:\n[%s]\n\t', strjoin(string(stereoParams.camPerViewReprojErr), ', '));
                sprintf('Projector per-view reprojection error:\n[%s]\n\t', strjoin(string(stereoParams.prjPerViewReprojErr), ', '));
                sprintf('Stereo per-view reprojection error:\n[%s]', strjoin(string(stereoParams.stereoPerViewReprojErr), ', '));
                };
        end
    end

    methods (Access = private)

        function initCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];
                if(any(contains(propertyNames, propertyName)))

                    app.(sliderName).Enable = true;
                    %                     app.(sliderName).Limits = double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Limits = str2double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Value = app.cam.(propertyName);
                    app.(sliderName).Tag = propertyName; % for later use
                else
                    app.(sliderName).Enable = false;
                end
            end

        end

        function resetCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];

                if(any(contains(propertyNames, propertyName)))

                    app.(sliderName).Value = double(app.camInfo{camID}.(propertyName).DefaultValue);
                    app.cam.(propertyName) = app.(sliderName).Value;

                end
            end

        end

        function initCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names

            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];

                if(any(contains(propertyNames, propertyName)))
                    app.(dropDownName).Items = app.camInfo{camID}.(propertyName).ConstraintValue;
                    app.(dropDownName).Tag = propertyName; % for later use

                    % matlab image aquisition toolbox's BacklightCompensation is a string, while webcam's is 0 or 1
                    if(strcmp(propertyName, 'BacklightCompensation'))
                        if(app.cam.(propertyName) == 1)
                            app.(dropDownName).Value = 'on';
                        else
                            app.(dropDownName).Value = 'off';
                        end
                    else
                        app.(dropDownName).Value = app.cam.(propertyName);
                    end

                    app.(dropDownName).Enable = true;
                else
                    app.(dropDownName).Enable = false;
                end
            end

        end


        function resetCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];

                if(any(contains(propertyNames, propertyName)))
                    app.(dropDownName).Value = app.camInfo{camID}.(propertyName).DefaultValue;

                    try
                        if(strcmp(propertyName, 'BacklightCompensation'))
                            app.cam.(propertyName) =  strcmp(app.(dropDownName).Value, 'on');
                        else
                            app.cam.(propertyName) = app.(dropDownName).Value;
                        end
                    catch e
                        disp(e) % for debug
                    end
                end
            end
        end


        function projectImage(app, im)
            screenID = app.ProjecionScreenDropDown.Value;

            if(isempty(app.prjFigHandel) || ~isvalid(app.prjFigHandel))
                app.prjFigHandel = initPrjWindow(app, screenID, im);
                app.prjFigHandel.DeleteFcn = createCallbackFcn(app, @disablePreviewPrjStateBtn, true); % set preview state button to disable when window is closed
            else
                cla;
                set(app.prjFigHandel, 'WindowState', 'normal');
                imshow(im);

                axisHandle = gca;
                set(axisHandle, 'Position', [0 0 1 1]);

                set(app.prjFigHandel, 'Position', app.screenSize(screenID,:))
                set(app.prjFigHandel, 'WindowState', 'fullscreen');
            end
        end

        % preview the input image in a axis specified by its name
        function previewImage(app, axisName, im)
            app.(axisName).PlotBoxAspectRatio = [size(im, 2), size(im, 1), size(im, 1)];
            app.(axisName).XLim = [0 size(im, 2)];
            app.(axisName).YLim = [0 size(im, 1)];
            image(app.(axisName), im);
        end

        % use try catch to get camera property constraint values
        function getCamPropertyConstraint(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names

            % sliders
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};

                if(any(contains(propertyNames, propertyName)))
                    try
                        app.cam.(propertyName) = inf;
                    catch e
                        app.camInfo{camID}.(propertyName).ConstraintValue = regexp(e.message,'-?\d*','match');
                    end

                    app.camInfo{camID}.(propertyName).DefaultValue = app.cam.(propertyName);
                end
            end

            % dropdowns
            if(any(contains(propertyNames, 'BacklightCompensation')))
                app.camInfo{camID}.BacklightCompensation.ConstraintValue = {'on', 'off'};
                app.camInfo{camID}.BacklightCompensation.DefaultValue = 'off';
            end

            if(any(contains(propertyNames, 'ExposureMode')))
                app.camInfo{camID}.ExposureMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.ExposureMode.DefaultValue = 'manual';
            end

            if(any(contains(propertyNames, 'WhiteBalanceMode')))
                app.camInfo{camID}.WhiteBalanceMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.WhiteBalanceMode.DefaultValue = 'auto';
            end
            
            if(any(contains(propertyNames, 'FocusMode')))
                app.camInfo{camID}.FocusMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.FocusMode.DefaultValue = 'manual';
            end
            % set ExposureMode to manual and BacklightCompensation to off, since our calibration algorithm requires the lightGrid and colorGrid images have the same background brightness
        end

        function saveCalibInfo(app, imLightGrid)
            if(isempty(app.calibInfo))
                % resize image to width = 640, since MATLAB detectCheckerboardPoints may fail on large images.
                if(size(imLightGrid,2) > 640)
                    imLightGridSmall = imresize(imLightGrid, [nan, 640]);
                else
                    imLightGridSmall = imLightGrid;
                end
                
                % find checkerboard #corners
                [~, boardSize, ~] = detectCheckerboardPoints(imLightGridSmall);

                if(any(boardSize == 0))
                    return;
                end
                
                % cam and prj resolutions
                app.calibInfo.camW = size(imLightGrid, 2);
                app.calibInfo.camH = size(imLightGrid, 1);

                app.calibInfo.prjW = size(app.imPattern, 2);
                app.calibInfo.prjH = size(app.imPattern, 1);
                
                % checkerboard #corners and square size
                app.calibInfo.boardSize = boardSize;
                app.calibInfo.sqSize = app.CheckerboardSquareSizemmEditField.Value;
            end
            
            % update sets
            dataFolder = fullfile(app.dataRoot, app.dataName);
            updateImageList(app, dataFolder);
            app.calibInfo.sets = cellfun(@(x) str2num(x(end-1:end)), app.ImageListBox.Items);

            %             cv.FileStorage('calib-info.yml', app.calibInfo);
            writeToYML(app, app.calibInfo, fullfile(app.dataRoot, app.dataName, 'calib-info.yml'));
        end

        function writeToYML(app, obj, fileName)
            fieldNames = fieldnames(obj);
            f = fopen(fileName, 'w');
            fprintf(f, '%s\n', '%YAML:1.0');
            fprintf(f, '%s\n', '# This is the calibration config file for later use');

            for i = 1: numel(fieldNames)
                fieldName = fieldNames{i};
                data = obj.(fieldName);
                len = length(data);
                if(len > 1)
                    str = [];
                    for j=1:len
                        if(j==1)
                            str = num2str(data(j));
                        else
                            str = [str, ', ' , num2str(data(j))];
                        end

                    end
                    fprintf(f, [fieldName, ': [%s]\n'], str);
                else
                    fprintf(f, [fieldName, ': ', num2str(data),'\n']);
                end
            end
            fclose(f);
        end

        function startCamPreview(app)
            app.CamPreviewButton.Value = 1;
            app.CameraControlPanel.Visible = true;

            app.camPreviewImg = app.cam.preview;
            f = ancestor(app.camPreviewImg, 'figure');
            f.DeleteFcn = createCallbackFcn(app, @disablePreviewCamStateBtn, true); % set preview state button to disable when window is closed
        end

        function setComponentKeyVal(app, fieldName, key, val)
            app.(fieldName).(key) = val;
        end

        function disablePreviewCamStateBtn(app, callbackData)
            if(isvalid(app))
                app.CamPreviewButton.Value = 0;
                %                 app.CameraAdjusmentPanel.Visible = false;
            end
        end

        function disablePreviewPrjStateBtn(app, callbackData)
            if(isvalid(app))
                app.ProjectorPreviewButton.Value = 0;
                app.ProjectorBrightnessSlider.Enable = false;
            end
        end


        function updateImageList(app, dataFolder)
            setList = dir(fullfile(dataFolder, '*light*.png'));
            app.ImageListBox.Items = {};

            for i=1:numel(setList)
                fileName = setList(i).name;
                app.ImageListBox.Items{i} = ['Set', fileName(end-5:end-4)];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DatafolderLabel_CapTab.Text = ['Data folder: ', fullfile(app.dataRoot, app.dataName)];
            app.DatafolderLabel_CalibTab.Text = ['Data folder: ', fullfile(app.dataRoot, app.dataName)];
            initCameras(app);
            getScreenInfo(app);
        end

        % Value changed function: CameraNameDropDown
        function CameraNameDropDownValueChanged(app, event)
            camName = app.CameraNameDropDown.Value;
            camID = find(contains(app.CameraNameDropDown.Items, camName));

            if(app.CamPreviewButton.Value)
                app.cam = webcam(camID);

                % also get this cam's property contraint
                if(length(app.camInfo)<camID || isempty(app.camInfo{camID}))
                    getCamPropertyConstraint(app, camID);
                end

                app.startCamPreview;
                updateCamControlPannel(app, camID);
                updateCamResolutionDropDown(app);
            end

        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            camName = app.CameraNameDropDown.Value;
            if(isempty(camName))
                uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
                return;
            end

            screenID = app.ProjecionScreenDropDown.Value;

            % larger exposure value should have larger exposureTime, if you see partial color grid (due to insufficient expoure time), increase exposureTime.
            exposureTime = app.CamExposureTime;

            % project pure black image and capture imLightGrid
            imBlack = zeros(app.screenSize(screenID, 4), app.screenSize(screenID, 3), 3); % in case desktop provides some lighting, we cover the fullscreen with a black image

            if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                app.prjFigHandel.Children.Children.CData = imBlack;
            else
                projectImage(app, imBlack);
            end
            
            % clear buffer
            pause(exposureTime);
            for j = 1:10
                app.cam.snapshot;
            end

            % start cam preview
            if(isempty(app.camPreviewImg))
                app.CamPreviewButton.Value = 1; % enable preview
                app.CamPreviewButtonValueChanged();
            end

%             imLightGrid = app.camPreviewImg.CData; % faster for MATLAB<=2019b
            imLightGrid = app.cam.snapshot; % faster since MATLAB>2019b

            % project color grid and capture imColorGrid
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
            if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                app.prjFigHandel.Children.Children.CData = app.imPattern;
            else
                projectImage(app, app.imPattern);
            end
            
            % clear buffer
            pause(exposureTime);
            for j = 1:10
                app.cam.snapshot;
            end
%             imColorGrid = app.camPreviewImg.CData;
            imColorGrid = app.cam.snapshot;

            % for DeProCams Cannon6D with CamLink 4K. Comment this for other cases
            if(contains(camName, 'Cam Link 4K'))
                imLightGrid = imresize(imLightGrid(:, 240:1679,:), [240, 320]);
                imColorGrid = imresize(imColorGrid(:, 240:1679,:), [240, 320]);
            end
            % preview
%             previewImage(app, 'CamLigthtGridUIAxes', imLightGrid);
%             previewImage(app, 'CamColorGridUIAxes', imColorGrid);
            
            app.CamLightGridImg.ImageSource = imLightGrid;
            app.CamColorGridImg.ImageSource = imColorGrid;
                        
            % check if the two images are similar (due to camera's buffer or small exposure time)
            if(immse(im2double(imLightGrid), im2double(imColorGrid)) < 0.01)
                uialert(app.ProCamCalibUIFigure, 'Seems that the camera light image is the same as color grid image, try a longer exposure time and recapture','Error');
                return;
            end

            % delete color grid figure
            %             delete(app.prjFigHandel);
            %             app.prjFigHandel = [];
            %
            % check if there is any existing sets
            dataFolder = fullfile(app.dataRoot, app.dataName);
            if(~exist(dataFolder,'dir'))
                app.dataName = ['calibration-', datestr(now,'mm-dd-yy_HH-MM-SS')];
                dataFolder = fullfile(app.dataRoot, app.dataName);
                mkdir(dataFolder);
                app.DatafolderLabel_CapTab.Text = ['Data folder: ', dataFolder];
                app.DatafolderLabel_CalibTab.Text = ['Data folder: ', dataFolder];
            end

            updateImageList(app, dataFolder);
            %             app.imgCounter = numel(app.ImageListBox.Items) + 1; % reset image counter

            % if empty sets
            if(numel(app.ImageListBox.Items) == 0)
                missingIdx = [1];
            else
                % check if some sets were missing, if so fill them using the newly captured images
                existingIdx = cellfun(@(x) str2num(x(end-1:end)), app.ImageListBox.Items);
                missingIdx = setdiff(1:str2num(app.ImageListBox.Items{end}(end-1:end)), existingIdx);
                if(isempty(missingIdx))
                    missingIdx = [existingIdx(end) + 1];
                end               
            end

            % save captured images
            imwrite(imLightGrid, fullfile(dataFolder, ['lightGrid', sprintf('%02d', missingIdx(1)), '.png']));
            imwrite(imColorGrid, fullfile(dataFolder, ['colorGrid', sprintf('%02d', missingIdx(1)), '.png']));
%             app.imgCounter = app.imgCounter + 1;

            % save calib-info
            saveCalibInfo(app, imLightGrid);
        end

        % Close request function: ProCamCalibUIFigure
        function ProCamCalibUIFigureCloseRequest(app, event)
            delete(app.prjFigHandel);
            delete(app);
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            if(app.CamPreviewButton.Value)
                app.cam.Resolution = app.ResolutionDropDown.Value;
                app.startCamPreview;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            camID = find(contains(app.CameraNameDropDown.Items, app.CameraNameDropDown.Value));
            if(~isempty(camID))
                resetCamSliders(app, camID);
                resetCamDropDowns(app, camID);
            end
        end

        % Value changed function: BacklightCompensationDropDown, 
        % ExposureModeDropDown, FocusModeDropDown, 
        % WhiteBalanceModeDropDown
        function camDropDownValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually

            try
                if(strcmp(propertyName, 'BacklightCompensation'))
                    app.cam.BacklightCompensation = strcmp(app.BacklightCompensationDropDown.Value, 'on');
                else
                    app.cam.(propertyName) = app.([propertyName, 'DropDown']).Value;
                end
            catch e
                disp(e) % for debug
            end
        end

        % Value changed function: ProjecionScreenDropDown
        function ProjecionScreenDropDownValueChanged(app, event)
            screenID = app.ProjecionScreenDropDown.Value;
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
%             previewImage(app, 'ProUIAxes', app.imPattern);
            app.ProGridImg.ImageSource = app.imPattern;
        end

        % Callback function: BrightnessSlider, ContrastSlider, 
        % ExposureSlider, GainSlider, GammaSlider, HueSlider, 
        % SaturationSlider, SharpnessSlider, WhiteBalanceSlider
        function camSliderValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            if(~isempty(app.cam))
                try
                    app.cam.(propertyName) = app.([propertyName, 'Slider']).Value;
                catch e
                    disp(e) % for debug
                end
            end
        end

        % Button pushed function: BrowseCaptureSaveFolderButton
        function BrowseCaptureSaveFolderButtonPushed(app, event)
            root = uigetdir(app.dataRoot, 'Select where you want to save the capture data');
            figure(app.ProCamCalibUIFigure);
            if(root)
                app.dataRoot = root;
                app.dataName = ['calibration-', datestr(now,'mm-dd-yy_HH-MM-SS')];
                dataFolder = fullfile(app.dataRoot, app.dataName);

                if(~exist(dataFolder, 'dir'))
                    mkdir(dataFolder);
                end

                app.DatafolderLabel_CapTab.Text = ['Data folder: ', dataFolder];
                app.DatafolderLabel_CalibTab.Text = ['Data folder: ', dataFolder];
                
                % empty calibinfo
                app.calibInfo = [];
            end
        end

        % Button pushed function: BrowseCalibrationFolderButton
        function BrowseCalibrationFolderButtonPushed(app, event)
            dataFolder = uigetdir(app.dataRoot, 'Select where calibration data is stored');
            figure(app.ProCamCalibUIFigure);
            if(dataFolder)
                backSlashidx = strfind(dataFolder, '\');
                app.dataRoot = dataFolder(1:backSlashidx(end)-1);
                app.dataName = dataFolder(backSlashidx(end)+1:end);

                app.DatafolderLabel_CapTab.Text = ['Data folder: ', dataFolder];
                app.DatafolderLabel_CalibTab.Text = ['Data folder: ', dataFolder];

                updateImageList(app, dataFolder);
               
                if(~isempty(app.ImageListBox.Items))
                    % update image preview
                    app.ImageListBox.Value = app.ImageListBox.Items{1};
                    ImageListBoxValueChanged(app, event);
                    
                    % load calib info
                    calibInfoFile = fullfile(dataFolder, 'calib-info.yml');
                    if(exist(calibInfoFile, 'file'))
                        app.calibInfo = cv.FileStorage(fullfile(dataFolder, 'calib-info.yml'));
                        % convert cell to mat
                        if(isa(app.calibInfo.boardSize,'cell'))
                            app.calibInfo.boardSize = cell2mat(app.calibInfo.boardSize);
                        end
                        if(isa(app.calibInfo.sets,'cell'))
                            app.calibInfo.sets = cell2mat(app.calibInfo.sets);
                        end
                    end
                    
                    % load calibration results
                    calibFileFullName = fullfile(dataFolder, 'results/calibration.yml');
                    if(exist(calibFileFullName, 'file'))
                        app.stereoParams = cv.FileStorage(calibFileFullName);
                        app.updateCalibrationResultText(calibFileFullName, app.stereoParams);
                    end
                else
                    %                     if(~isempty(app.CalibUIAxes.Children))
                    %                         if(~isempty(app.CalibUIAxes.Children.CData))
                    %                             app.CalibUIAxes.Children.CData = [];
                    %                         end
                    %                     end
                    if(~isempty(app.CalibImage.ImageSource))
                        app.CalibImage.ImageSource = zeros(1,1,3,'uint8'); % an empty black image
                    end
                    uialert(app.ProCamCalibUIFigure, 'No lightGird image found in current folder!', 'No image found');
                end
            end
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)
            app.previewCalibImageType = app.PreviewImageTypeButtonGroup.SelectedObject.Text;
            imgName = app.ImageListBox.Value;
            im = imread(fullfile(app.dataRoot, app.dataName, [app.previewCalibImageType, imgName{1}(end-1:end), '.png']));
%             previewImage(app, 'CalibUIAxes', im);
            app.CalibImage.ImageSource = im;
        end

        % Selection changed function: PreviewImageTypeButtonGroup
        function PreviewImageTypeButtonGroupSelectionChanged(app, event)
            if(~isempty(app.ImageListBox.Value))
                app.previewCalibImageType = app.PreviewImageTypeButtonGroup.SelectedObject.Text;
                ImageListBoxValueChanged(app, event);
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ImageListBox.Value = app.ImageListBox.Items;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            selectedSets = app.ImageListBox.Value;
            selectedIdx = cell2mat(cellfun(@(x) str2num(x(end-1:end)), selectedSets, 'UniformOutput', false));

            % check if at least 3 sets are selected
            if(numel(selectedSets) >= 3)
                app.calibOption.sets = selectedIdx;
                app.calibOption.sqSize = app.CheckerboardSquareSizemmEditField.Value;
                app.calibOption.verbose = app.VerbosedebugCheckBox.Value;
                app.calibOption.useParallel = app.UseParallelCheckBox.Value;
                app.calibOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
                app.calibOption.useExistingCamCorners = app.UseExistingCamCornersCheckBox.Value;
                app.calibOption.useDistortion = app.UseDistortionCheckBox.Value;

                calibrateGUI(app); % call calibration function
            else
                uialert(app.ProCamCalibUIFigure, 'Need at lease 3 sets to calibrate!', 'Not enough sets');
            end
            
        end

        % Value changed function: CamPreviewButton
        function CamPreviewButtonValueChanged(app, event)
            camPreviewEnabled = app.CamPreviewButton.Value;

            if(camPreviewEnabled)
                camName = app.CameraNameDropDown.Value;

                if(isempty(camName))
                    uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
                    return;
                end

                % init cam if it was not initialized
                if(isempty(app.cam) || ~strcmp(camName, app.cam.Name))
                    camID = find(contains(app.CameraNameDropDown.Items, camName));
                    app.cam = webcam(camID);

                    % also get this cam's property contraint
                    if(length(app.camInfo)<camID)
                        getCamPropertyConstraint(app, camID);
                    end

                    % update camera control panel and resolution list
                    updateCamControlPannel(app, camID);
                    updateCamResolutionDropDown(app);
                end

                app.startCamPreview;
                %                 app.CameraAdjusmentPanel.Visible = true;
            else
                %                 app.CameraAdjusmentPanel.Visible = false;

                if(~isempty(app.cam))
                    app.cam.closePreview;
                end
            end
        end

        % Value changed function: CheckerboardSquareSizemmEditField
        function CheckerboardSquareSizemmEditFieldValueChanged(app, event)
            app.calibOption.sqSize = app.CheckerboardSquareSizemmEditField.Value;
        end

        % Value changed function: VerbosedebugCheckBox
        function VerbosedebugCheckBoxValueChanged(app, event)
            app.calibOption.verbose = app.VerbosedebugCheckBox.Value;
        end

        % Value changed function: UseExistingCamCornersCheckBox
        function UseExistingCamCornersCheckBoxValueChanged(app, event)
            app.calibOption.useExistingCamCorners = app.UseExistingCamCornersCheckBox.Value;
        end

        % Value changed function: ProjectorPreviewButton
        function ProjectorPreviewButtonValueChanged(app, event)

            if(app.ProjectorPreviewButton.Value)
                app.ProjectorBrightnessSlider.Enable = true;
                screenID = app.ProjecionScreenDropDown.Value;

                % project color grid and capture imColorGrid
                app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
                projectImage(app, app.imPattern);
            else
                app.ProjectorBrightnessSlider.Enable = false;
                if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                    % delete color grid figure
                    delete(app.prjFigHandel);
                    app.prjFigHandel = [];
                end
            end
        end

        % Value changed function: ProjectorBrightnessSlider
        function ProjectorBrightnessSliderValueChanged(app, event)
            app.prjBrightness = app.ProjectorBrightnessSlider.Value;
            screenID = app.ProjecionScreenDropDown.Value;
            app.imPattern = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4), app.prjBrightness);
%             previewImage(app, 'ProUIAxes', app.imPattern);
            app.ProGridImg.ImageSource = app.imPattern;

            if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                app.prjFigHandel.Children.Children.CData = app.imPattern;
            end
        end

        % Button pushed function: AutoSegButton
        function AutoSegButtonPushed(app, event)
            if(isempty(event))
                % invoked by reconstructGUI
                return;
            end

            imgName = app.ImageListBox.Value;

            if(isempty(imgName))
                uialert(app.ProCamCalibUIFigure, 'No image selected','Error');
                return;
            end

            whiteLight = fullfile(app.dataRoot, app.dataName, ['lightGrid', imgName{1}(end-1:end), '.png']);
            colorGrid = fullfile(app.dataRoot, app.dataName, ['colorGrid', imgName{1}(end-1:end), '.png']);

            [camCorners, ~, ~] = detectCheckerboardPoints(whiteLight);

            if(isempty(camCorners))
                uialert(app.ProCamCalibUIFigure, 'No checkerboard corners detected, cannot segment','Error');
                return;
            end

            [~, imColorGridMasked, imBWBoardMask] = ImgProc.segColorGrid(whiteLight, colorGrid, camCorners, false);

            if(strcmp(app.previewCalibImageType, 'lightGrid'))
%                 previewImage(app, 'CalibUIAxes', cat(3,imBWBoardMask,imBWBoardMask,imBWBoardMask));
                app.CalibImage.ImageSource = im2uint8(cat(3,imBWBoardMask,imBWBoardMask,imBWBoardMask));
            else
%                 previewImage(app, 'CalibUIAxes', imColorGridMasked);
                app.CalibImage.ImageSource = imColorGridMasked;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if(strcmp(selectedTab.Title, 'Calibration'))
                updateImageList(app, fullfile(app.dataRoot, app.dataName));
            end
        end

        % Button pushed function: ManualSegButton
        function ManualSegButtonPushed(app, event)
            imgName = app.ImageListBox.Value;

            if(isempty(imgName))
                uialert(app.ProCamCalibUIFigure, 'No image selected','Error');
                return;
            end

            whiteLight = fullfile(app.dataRoot, app.dataName, ['lightGrid', imgName{1}(end-1:end), '.png']);

            f = figure;
            imshow(imread(whiteLight));title('Draw ROI by clicking and dragging left mouse button')
            h = drawfreehand(f.Children);

            if(isvalid(h))
                imMask = createMask(h);
                if(isempty(event))
                    % invoked by reconstructGUI
                    imwrite(imMask, fullfile(app.dataRoot, app.dataName, ['objectROI', imgName{1}(end-1:end), '.png']));
                else
                    imwrite(imMask, fullfile(app.dataRoot, app.dataName, ['bwBoard', imgName{1}(end-1:end), '.png']));
                end

                delete(f);

                % invoke auto seg
                AutoSegButtonPushed(app, event);
            end
        end

        % Button pushed function: LoadCalibrationButton
        function LoadCalibrationButtonPushed(app, event)
            [calibFileName, calibFilePath] = uigetfile(fullfile(app.dataRoot, '.yml'), 'Select existing calibration result file');
            figure(app.ProCamCalibUIFigure);
            if(calibFileName)
                calibFileFullName = fullfile(calibFilePath, calibFileName);
                app.stereoParams = cv.FileStorage(calibFileFullName);
                app.updateCalibrationResultText(calibFileFullName, app.stereoParams);
            end
        end

        % Value changed function: ExposureTimesSlider
        function ExposureTimesSliderValueChanged(app, event)
            app.CamExposureTime = app.ExposureTimesSlider.Value;
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            if(isempty(app.stereoParams))
                uialert(app.ProCamCalibUIFigure, 'No calibration parameters, click Load Calibration and load results/calibration.yml','Error');
                return;
            else
                selectedSets = app.ImageListBox.Value;

                % check if at least 3 sets are selected
                if(numel(selectedSets) > 1)
                    uialert(app.ProCamCalibUIFigure, 'Only one set is allowed for reconstruction each time','Error');
                    return;
                else
                    selectedIdx = cell2mat(cellfun(@(x) str2num(x(end-1:end)), selectedSets, 'UniformOutput', false));
                    app.reconOption.sets = selectedIdx;
                    app.reconOption.verbose = app.VerbosedebugCheckBox_2.Value;
                    app.reconOption.epiThresh = app.EpipolarThreshpixelEditField.Value;
                    app.reconOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
                    app.reconOption.useEdge = app.UseEdgesCheckBox.Value;
                end

                reconstructGUI(app);
            end
        end

        % Value changed function: EpipolarThreshpixelEditField
        function EpipolarThreshpixelEditFieldValueChanged(app, event)
            app.reconOption.epiThresh = app.EpipolarThreshpixelEditField.Value;
        end

        % Value changed function: UseParallelCheckBox
        function UseParallelCheckBoxValueChanged(app, event)
            app.calibOption.useParallel = app.UseParallelCheckBox.Value;
        end

        % Value changed function: UseExistingMaskCheckBox
        function UseExistingMaskCheckBoxValueChanged(app, event)
            app.calibOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
        end

        % Value changed function: UseDistortionCheckBox
        function UseDistortionCheckBoxValueChanged(app, event)
            app.calibOption.useDistortion = app.UseDistortionCheckBox.Value;
        end

        % Value changed function: VerbosedebugCheckBox_2
        function VerbosedebugCheckBox_2ValueChanged(app, event)
            app.reconOption.verbose = app.VerbosedebugCheckBox_2.Value;
        end

        % Value changed function: UseEdgesCheckBox
        function UseEdgesCheckBoxValueChanged(app, event)
            app.reconOption.useEdge = app.UseEdgesCheckBox.Value;
        end

        % Callback function
        function SetupImageClicked(app, event)
            system('start https://github.com/BingyaoHuang/DeProCams');
        end

        % Callback function: DeleteButton, DeleteMenu
        function DeleteMenuSelected(app, event)
            dataFolder = fullfile(app.dataRoot, app.dataName);
            
            selectedSets = app.ImageListBox.Value;
            selectedIdx = cellfun(@(x) x(end-1:end), selectedSets, 'UniformOutput', false);

            for i=1:numel(selectedIdx)
                for fileType = {'lightGrid', 'colorGrid', 'bwBoard', 'objectROI'}
                    imgName = fullfile(dataFolder, [fileType{1}, selectedIdx{i}, '.png']);
                    if(exist(imgName, 'file'))
                        delete(imgName);
                    end
                    
                    % also delete point cloud ply
                    pcName = fullfile(dataFolder, ['pointCloud', selectedIdx{i}, '.ply']);
                    if(exist(pcName, 'file'))
                        delete(pcName);
                    end
                end
            end
            updateImageList(app, dataFolder);
            
            % only update calib-info sets
            saveCalibInfo(app, []);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProCamCalibUIFigure = uifigure('Visible', 'off');
            app.ProCamCalibUIFigure.Name = 'ProCamCalib';
            app.ProCamCalibUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProCamCalibUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ProCamCalibUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.CameraControlPanel = uipanel(app.CaptureTab);
            app.CameraControlPanel.Tooltip = {'Control camera parameters. Tips: avoid camera overexposure by adjusting Exposure. Click Rest if you mess up with the parameters'};
            app.CameraControlPanel.Title = 'Camera Control';
            app.CaptureButton = uibutton(app.CameraControlPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Tooltip = {'Capture one set of calibration images. '; ''; 'Tips: '; '1. Capture as many poses as possible in case some sets do not work. '; '2. Try moving the white board so the checkerboard is imaged at different angle and distance. '; '3. Make sure the color grid covers white board'};
            app.CaptureButton.Text = 'Capture';
            app.CamPreviewButton = uibutton(app.CameraControlPanel, 'state');
            app.CamPreviewButton.ValueChangedFcn = createCallbackFcn(app, @CamPreviewButtonValueChanged, true);
            app.CamPreviewButton.Tooltip = {'Open camera preview to adjust camera parameters in order to get a good capture. You can also click PreviewPrj to view the projected pattern in camera preview'};
            app.CamPreviewButton.Text = 'Preview';
            app.CameraNameDropDownLabel = uilabel(app.CameraControlPanel);
            app.CameraNameDropDownLabel.Text = 'Camera Name';
            app.CameraNameDropDown = uidropdown(app.CameraControlPanel);
            app.CameraNameDropDown.Items = {};
            app.CameraNameDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraNameDropDownValueChanged, true);
            app.CameraNameDropDown.Tooltip = {'Select the camera to be calibrated then click PreviewCam to preview camera live frames'};
            app.CameraNameDropDown.Value = {};
            app.ResolutionDropDownLabel = uilabel(app.CameraControlPanel);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.CameraControlPanel);
            app.ResolutionDropDown.Items = {};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Tooltip = {'List of supported resolution of your selected camera.'};
            app.ResolutionDropDown.Value = {};
            app.CameraAdjusmentPanel = uipanel(app.CameraControlPanel);
            app.CameraAdjusmentPanel.Title = 'Camera Adjusment';
            app.FixtooffLabel = uilabel(app.CameraAdjusmentPanel);
            app.FixtooffLabel.Text = 'Fix to ''off'' ';
            app.FixtomanualLabel = uilabel(app.CameraAdjusmentPanel);
            app.FixtomanualLabel.Text = 'Fix to ''manual'' ';
            app.ResetButton = uibutton(app.CameraAdjusmentPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ContrastSlider = uislider(app.CameraAdjusmentPanel);
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ContrastSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ExposureSlider = uislider(app.CameraAdjusmentPanel);
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.ExposureSlider.MinorTicks = [];
            app.ExposureSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.GainSlider = uislider(app.CameraAdjusmentPanel);
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.GainSlider.MinorTicks = [];
            app.GainSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GammaSlider = uislider(app.CameraAdjusmentPanel);
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.GammaSlider.MinorTicks = [];
            app.GammaSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.HueSlider = uislider(app.CameraAdjusmentPanel);
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.HueSlider.MinorTicks = [];
            app.HueSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.SaturationSlider = uislider(app.CameraAdjusmentPanel);
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.SaturationSlider.MinorTicks = [];
            app.SaturationSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SharpnessSlider = uislider(app.CameraAdjusmentPanel);
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.SharpnessSlider.MinorTicks = [];
            app.SharpnessSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.WhiteBalanceSlider = uislider(app.CameraAdjusmentPanel);
            app.WhiteBalanceSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.WhiteBalanceSlider.MinorTicks = [];
            app.WhiteBalanceSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.WhiteBalanceSliderLabel.Text = 'WhiteBalance';
            app.ExposureModeDropDown = uidropdown(app.CameraAdjusmentPanel);
            app.ExposureModeDropDown.Items = {};
            app.ExposureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.ExposureModeDropDown.Value = {};
            app.ExposureModeDropDownLabel = uilabel(app.CameraAdjusmentPanel);
            app.ExposureModeDropDownLabel.Text = 'ExposureMode';
            app.WhiteBalanceModeDropDown = uidropdown(app.CameraAdjusmentPanel);
            app.WhiteBalanceModeDropDown.Items = {};
            app.WhiteBalanceModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.WhiteBalanceModeDropDown.Value = {};
            app.WhiteBalanceModeDropDownLabel = uilabel(app.CameraAdjusmentPanel);
            app.WhiteBalanceModeDropDownLabel.Text = 'WhiteBalanceMode';
            app.BacklightCompensationDropDown = uidropdown(app.CameraAdjusmentPanel);
            app.BacklightCompensationDropDown.Items = {};
            app.BacklightCompensationDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.BacklightCompensationDropDown.Value = {};
            app.BacklightCompensationDropDownLabel = uilabel(app.CameraAdjusmentPanel);
            app.BacklightCompensationDropDownLabel.Text = 'BacklightCompensation';
            app.BrightnessSlider = uislider(app.CameraAdjusmentPanel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @camSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.ExposureTimesSlider = uislider(app.CameraAdjusmentPanel);
            app.ExposureTimesSlider.Limits = [0.1 2];
            app.ExposureTimesSlider.MajorTicks = [0.1 0.5 1 1.5 2];
            app.ExposureTimesSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureTimesSliderValueChanged, true);
            app.ExposureTimesSlider.MinorTicks = [];
            app.ExposureTimesSlider.Tooltip = {'Increase exposure time when the camera-captured color grid image does not have color grid.'};
            app.ExposureTimesSlider.Value = 0.1;
            app.ExposureTimesSliderLabel = uilabel(app.CameraAdjusmentPanel);
            app.ExposureTimesSliderLabel.Text = 'Exposure Time (s)';
            app.FocusModeDropDown = uidropdown(app.CameraAdjusmentPanel);
            app.FocusModeDropDown.Items = {};
            app.FocusModeDropDown.ValueChangedFcn = createCallbackFcn(app, @camDropDownValueChanged, true);
            app.FocusModeDropDown.Value = {};
            app.FocusModeDropDownLabel = uilabel(app.CameraAdjusmentPanel);
            app.FocusModeDropDownLabel.Text = 'FocusMode';
            app.PreviewPanelCapture = uipanel(app.CaptureTab);
            app.PreviewPanelCapture.Tooltip = {'The latest captured data will be displayed here. If you do not see color grid in Camera color grid image'; ' try increasing the exposure time in Camera Control pannel'};
            app.PreviewPanelCapture.Title = 'Projected and Captured Images';
            app.GridLayout = uigridlayout(app.PreviewPanelCapture);
            app.ProGridImg = uiimage(app.GridLayout);
            app.CamLightGridImg = uiimage(app.GridLayout);
            app.CamColorGridImg = uiimage(app.GridLayout);
            app.ProjectorinputImageLabel = uilabel(app.GridLayout);
            app.ProjectorinputImageLabel.Text = 'Projector input Image';
            app.CameraLightImageLabel = uilabel(app.GridLayout);
            app.CameraLightImageLabel.Text = 'Camera Light Image';
            app.CameraColorGridImageLabel = uilabel(app.GridLayout);
            app.CameraColorGridImageLabel.Text = 'Camera Color Grid Image';
            app.BrowseCaptureSaveFolderButton = uibutton(app.CaptureTab, 'push');
            app.BrowseCaptureSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCaptureSaveFolderButtonPushed, true);
            app.BrowseCaptureSaveFolderButton.Tooltip = {'Browse the directory where you want to save the calibration data. A data folder will be automatically created under the selected directory'};
            app.BrowseCaptureSaveFolderButton.Text = 'Browse data root';
            app.DatafolderLabel_CapTab = uilabel(app.CaptureTab);
            app.DatafolderLabel_CapTab.Text = 'Data folder:';
            app.ProjectorControlPanel = uipanel(app.CaptureTab);
            app.ProjectorControlPanel.Title = 'Projector Control';
            app.ProjectorBrightnessLabel = uilabel(app.ProjectorControlPanel);
            app.ProjectorBrightnessLabel.Text = 'Projector Brightness';
            app.ProjectorBrightnessSlider = uislider(app.ProjectorControlPanel);
            app.ProjectorBrightnessSlider.Limits = [0 1];
            app.ProjectorBrightnessSlider.MajorTicks = [0 0.5 1];
            app.ProjectorBrightnessSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ProjectorBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ProjectorBrightnessSliderValueChanged, true);
            app.ProjectorBrightnessSlider.MinorTicks = [];
            app.ProjectorBrightnessSlider.Enable = 'off';
            app.ProjectorBrightnessSlider.Tooltip = {'Adjust the brightness of the projected color grid. Tips: decrease the brightness according to the camera preview'};
            app.ProjectorBrightnessSlider.Value = 1;
            app.ProjectorPreviewButton = uibutton(app.ProjectorControlPanel, 'state');
            app.ProjectorPreviewButton.ValueChangedFcn = createCallbackFcn(app, @ProjectorPreviewButtonValueChanged, true);
            app.ProjectorPreviewButton.Tooltip = {'Preview projected color grid. (The projector must be turned on and selected in the drop down on the left)'};
            app.ProjectorPreviewButton.Text = 'Preview';
            app.ProjecionScreenDropDownLabel = uilabel(app.ProjectorControlPanel);
            app.ProjecionScreenDropDownLabel.Text = 'Projecion Screen';
            app.ProjecionScreenDropDown = uidropdown(app.ProjectorControlPanel);
            app.ProjecionScreenDropDown.Items = {};
            app.ProjecionScreenDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjecionScreenDropDownValueChanged, true);
            app.ProjecionScreenDropDown.Tooltip = {'Select projector screen'; ' then click PreviewPrj'};
            app.ProjecionScreenDropDown.Value = {};
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Panel = uipanel(app.CalibrationTab);
            app.UseEdgesCheckBox = uicheckbox(app.Panel);
            app.UseEdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEdgesCheckBoxValueChanged, true);
            app.UseEdgesCheckBox.Tooltip = {'Also reconstruct Edge pixels.'};
            app.UseEdgesCheckBox.Text = 'Use Edges';
            app.UseEdgesCheckBox.Value = true;
            app.VerbosedebugCheckBox_2 = uicheckbox(app.Panel);
            app.VerbosedebugCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VerbosedebugCheckBox_2ValueChanged, true);
            app.VerbosedebugCheckBox_2.Tooltip = {'Check this when you want to see the intermediate results. Parallel will be disabled in debug mode.'};
            app.VerbosedebugCheckBox_2.Text = 'Verbose (debug)';
            app.UseDistortionCheckBox = uicheckbox(app.Panel);
            app.UseDistortionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDistortionCheckBoxValueChanged, true);
            app.UseDistortionCheckBox.Tooltip = {'Estimate ProCams lens  distortions. DeProCams assumes no distortion, we may include distortion in the future.'};
            app.UseDistortionCheckBox.Text = 'Use Distortion';
            app.UseDistortionCheckBox.Value = true;
            app.UseExistingMaskCheckBox = uicheckbox(app.Panel);
            app.UseExistingMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseExistingMaskCheckBoxValueChanged, true);
            app.UseExistingMaskCheckBox.Tooltip = {'Use existing object ROI mask (objectROIxx.png) rather than user drawn.'};
            app.UseExistingMaskCheckBox.Text = 'Use Existing Mask';
            app.UseExistingMaskCheckBox.Value = true;
            app.UseParallelCheckBox = uicheckbox(app.Panel);
            app.UseParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBoxValueChanged, true);
            app.UseParallelCheckBox.Tooltip = {'Use MATLAB parfor to speedup calibration.'};
            app.UseParallelCheckBox.Text = 'Use Parallel';
            app.ReconstructButton = uibutton(app.Panel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Tooltip = {'Start 3D-reconstruction using the calibration data and the selected set.'};
            app.ReconstructButton.Text = 'Reconstruct';
            app.UseExistingCamCornersCheckBox = uicheckbox(app.Panel);
            app.UseExistingCamCornersCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseExistingCamCornersCheckBoxValueChanged, true);
            app.UseExistingCamCornersCheckBox.Visible = 'off';
            app.UseExistingCamCornersCheckBox.Text = 'Use Existing Cam Corners';
            app.VerbosedebugCheckBox = uicheckbox(app.Panel);
            app.VerbosedebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerbosedebugCheckBoxValueChanged, true);
            app.VerbosedebugCheckBox.Tooltip = {'Check this when you want to see the intermediate results. Parallel will be disabled in debug mode.'};
            app.VerbosedebugCheckBox.Text = 'Verbose (debug)';
            app.CalibrateButton = uibutton(app.Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Tooltip = {'Start pro-cam calibration based on the selected sets'};
            app.CalibrateButton.Text = 'Calibrate';
            app.CheckerboardSquareSizemmEditField = uieditfield(app.Panel, 'numeric');
            app.CheckerboardSquareSizemmEditField.ValueChangedFcn = createCallbackFcn(app, @CheckerboardSquareSizemmEditFieldValueChanged, true);
            app.CheckerboardSquareSizemmEditField.Tooltip = {'Meansure your printed checkerboard square''s size (mm) and type it here. Incorrect size will produce incorrect calibrated translation vector.'};
            app.CheckerboardSquareSizemmEditField.Value = 24.5;
            app.CheckerboardSquareSizemmEditFieldLabel = uilabel(app.Panel);
            app.CheckerboardSquareSizemmEditFieldLabel.Text = 'Checkerboard Square Size (mm)';
            app.EpipolarThreshpixelEditField = uieditfield(app.Panel, 'numeric');
            app.EpipolarThreshpixelEditField.ValueChangedFcn = createCallbackFcn(app, @EpipolarThreshpixelEditFieldValueChanged, true);
            app.EpipolarThreshpixelEditField.Tooltip = {'Epipolar distance threshold for outlier matches.rejection.'};
            app.EpipolarThreshpixelEditField.Value = 1;
            app.EpipolarThreshpixelEditFieldLabel = uilabel(app.Panel);
            app.EpipolarThreshpixelEditFieldLabel.Text = 'Epipolar Thresh (pixel)';
            app.ImagesPanel = uipanel(app.CalibrationTab);
            app.ImagesPanel.Title = 'Images';
            app.SelectAllButton = uibutton(app.ImagesPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Tooltip = {'Select all sets'};
            app.SelectAllButton.Text = 'Select All';
            app.BrowseCalibrationFolderButton = uibutton(app.ImagesPanel, 'push');
            app.BrowseCalibrationFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCalibrationFolderButtonPushed, true);
            app.BrowseCalibrationFolderButton.Tooltip = {'Choose the data folder where the calibration images are stored.'};
            app.BrowseCalibrationFolderButton.Text = 'Browse';
            app.ImageListBox = uilistbox(app.ImagesPanel);
            app.ImageListBox.Items = {};
            app.ImageListBox.Multiselect = 'on';
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.ImageListBox.Value = {};
            app.DeleteButton = uibutton(app.ImagesPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteButton.Tooltip = {'Delete selected bad sets.'};
            app.DeleteButton.Text = 'Delete';
            app.DatafolderLabel_CalibTab = uilabel(app.CalibrationTab);
            app.DatafolderLabel_CalibTab.Text = 'Data folder:';
            app.LoadCalibrationButton = uibutton(app.CalibrationTab, 'push');
            app.LoadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationButtonPushed, true);
            app.LoadCalibrationButton.Tooltip = {'Load existing calibration data'};
            app.LoadCalibrationButton.Text = 'Load Calibration';
            app.PreviewPanelCalibration = uipanel(app.CalibrationTab);
            app.PreviewPanelCalibration.Title = 'Preview';
            app.AutoSegButton = uibutton(app.PreviewPanelCalibration, 'push');
            app.AutoSegButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSegButtonPushed, true);
            app.AutoSegButton.Tooltip = {'Pre-segment the *checkerboard* and color grid. If the result is bad (all black or too much background) delect (or unselect) this set, or use ManualSeg instead.'};
            app.AutoSegButton.Text = 'AutoSeg';
            app.ManualSegButton = uibutton(app.PreviewPanelCalibration, 'push');
            app.ManualSegButton.ButtonPushedFcn = createCallbackFcn(app, @ManualSegButtonPushed, true);
            app.ManualSegButton.Tooltip = {'Manually segment the white board area (or objects to be reconstructed). Hold and drag your mouse to select the region of white board.'; ''; 'This will create a mask to help calibration'};
            app.ManualSegButton.Text = 'ManualSeg';
            app.CalibImage = uiimage(app.PreviewPanelCalibration);
            app.PreviewImageTypeButtonGroup = uibuttongroup(app.PreviewPanelCalibration);
            app.PreviewImageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreviewImageTypeButtonGroupSelectionChanged, true);
            app.PreviewImageTypeButtonGroup.Tooltip = {'Select to preview white board and color grid images'};
            app.PreviewImageTypeButtonGroup.Title = 'Preview Image Type';
            app.lightGridButton = uiradiobutton(app.PreviewImageTypeButtonGroup);
            app.lightGridButton.Text = 'lightGrid';
            app.colorGridButton = uiradiobutton(app.PreviewImageTypeButtonGroup);
            app.colorGridButton.Text = 'colorGrid';
            app.colorGridButton.Value = true;
            app.CalibrationResultsTextAreaLabel = uilabel(app.CalibrationTab);
            app.CalibrationResultsTextAreaLabel.Text = 'Calibration Results';
            app.CalibrationResultsTextArea = uitextarea(app.CalibrationTab);
            app.CalibrationResultsTextArea.Tooltip = {'Calibration results are displayed here.'};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.TitleLabel = uilabel(app.AboutTab);
            app.TitleLabel.Text = 'A Fast and Flexible Projector-Camera Calibration System';
            app.CopyrightLabel = uilabel(app.AboutTab);
            app.CopyrightLabel.Text = '© 2021 Bingyao Huang';
            app.AuthorsLabel = uilabel(app.AboutTab);
            app.AuthorsLabel.Text = 'Bingyao Huang, Ying Tang, Samed Ozdemir and Haibin Ling';
            app.ConfLabel = uilabel(app.AboutTab);
            app.ConfLabel.Text = 'IEEE TASE 2020';
            app.VersionLabel = uilabel(app.AboutTab);
            app.VersionLabel.Text = 'Version: 0.2 (5/22/2021)';
            app.GitHubHyperlink = uihyperlink(app.AboutTab);
            app.GitHubHyperlink.URL = 'https://github.com/BingyaoHuang/single-shot-pro-cam-calib';
            app.GitHubHyperlink.Text = 'GitHub';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'setup.jpg';
            app.ContextMenu = uicontextmenu(app.ProCamCalibUIFigure);
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.ImageListBox.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ProCamCalibUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProCamCalibUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProCamCalibUIFigure)
        end
    end
end